classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BuildMapImage        matlab.ui.control.Image
        BuildMapBackground   matlab.ui.container.Panel
        Panel_2              matlab.ui.container.Panel
        SpeedKnob            matlab.ui.control.Knob
        SpeedLabel           matlab.ui.control.Label
        MapBuildingLamp      matlab.ui.control.Lamp
        MapbuildingLabel     matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        AutoexploreLabel     matlab.ui.control.Label
        AutoexploreSwitch    matlab.ui.control.Switch
        SimulationLamp       matlab.ui.control.Lamp
        SimulationLampLabel  matlab.ui.control.Label
        ConnectionLamp       matlab.ui.control.Lamp
        ConnectionLampLabel  matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        BtnFullStop          matlab.ui.control.StateButton
        BtnLeft              matlab.ui.control.StateButton
        BtnBackward          matlab.ui.control.StateButton
        BtnRight             matlab.ui.control.StateButton
        BtnForward           matlab.ui.control.StateButton
        BtnCW                matlab.ui.control.StateButton
        BtnCCW               matlab.ui.control.StateButton
        BtnSimulate          matlab.ui.control.Button
        BtnConnect           matlab.ui.control.Button
        cameraAxes           matlab.ui.control.UIAxes
        mapOccupancyAxes     matlab.ui.control.UIAxes
        mapTrajectoryAxes    matlab.ui.control.UIAxes
    end

    properties(Constant)

    end
    properties (Access = private)
        statusOffColor = [0.5, 0.5, 0.5];
        statusOnColor = 'green';
        wheelsSpeed = 10.0; 
        baseControl % Base control object
        connection  % Instance of RobotConnection
        laserScanner % Instance of LaserScanner class
        robotPose % Instance of RobotPoseEstimation class 
        slamHandler % Instance of the lidarSlam class
        explorer %instance of the explorer class
        autoExploreSpeed = 4;
        slamObj
        worldMap
        timerToDoShit
        timerToUpdateMap
        timerToMoveTheRobot
        intialPosition
        timerRecalculatePath

        currentPoseOfTheRobot
        plannedPath
        purePursuitController

        % Set goal radius
        goalRadius = 0.1;
        targetReached = false;

        minFrontDist
        
        cameraTimer 
        camHandle
        hImage 
    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)
        function killAllTimers(~)
            % Get all timer objects
            timers = timerfindall;
            
            % Stop and delete each timer
            if ~isempty(timers)
                stop(timers);
                disp('All timers have been stopped.');
            else
                disp('No active timers found.');
            end
            
        end
        function toggleConnectButton(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnectionLamp.Color = app.statusOffColor;
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnectionLamp.Color = app.statusOnColor;
            end
        end

        function toggleSimulationButton(app,state)
            if app.connection.clientID > -1
                app.BtnSimulate.Enable = "on";
            else 
                app.BtnSimulate.Enable = state;
            end

            if app.connection.isSimulationRunning
                app.SimulationLamp.Color = app.statusOnColor;
                app.BtnSimulate.Icon = 'icons/Stop.png';
            else
                app.SimulationLamp.Color = app.statusOffColor;
                app.BtnSimulate.Icon = 'icons/Start.png';
            end
        end

        function toggleBuildMapButtons(app)
            if isempty(app.slamHandler) || isempty(app.explorer)
                app.MapBuildingLamp.Color = app.statusOffColor;
                app.BuildMapImage.BackgroundColor = "#F0F0F0";
            elseif app.slamHandler.isUpdating || app.explorer.isExploring
                app.MapBuildingLamp.Color = app.statusOnColor;
                app.BuildMapImage.BackgroundColor = "white";
            else
                app.MapBuildingLamp.Color = app.statusOffColor;
                app.BuildMapImage.BackgroundColor = "#F0F0F0";
            end
        end
 
        function toggleMovementButtons(app,state)
            
            app.SpeedKnob.Enable = state;
            app.SpeedLabel.Enable = state;
            
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW, app.BtnFullStop};

            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
        end

        function toggleAutoExploreButton(app,state)
            app.AutoexploreSwitch.Enable = state;
            app.AutoexploreLabel.Enable = state;
             if strcmp(state,"off")
                app.AutoexploreSwitch.Value = 'Off';
            end
        end

        function toggleControls(app,state)
            app.toggleSimulationButton(state);
            app.toggleBuildMapButtons();
            app.toggleMovementButtons(state);
        end

        function buttonsInitialSetup(app)
        
            app.toggleConnectButton();
            app.toggleSimulationButton('off');
            app.toggleBuildMapButtons();
            app.toggleMovementButtons('off');
            app.toggleAutoExploreButton('off');

            %assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFullStop.Tag = 'STOP';

            app.SpeedKnob.Value = app.wheelsSpeed;
        end

        function shitInitiater(app)

            app.purePursuitController = controllerPurePursuit;
            app.purePursuitController.LookaheadDistance = 0.6;  % Look for the next waypoint 0.5 meters ahead
            app.purePursuitController.DesiredLinearVelocity = 0.2;  % Move at 0.3 m/s
            app.purePursuitController.MaxAngularVelocity = 0.5;  % Allow up to 2 rad/s angular velocity


            maxRange = 19.2; % meters
            mapResolution = 10; % cells per meter
            app.slamObj = lidarSLAM(mapResolution,maxRange);
            app.slamObj.LoopClosureThreshold = 360;
            app.slamObj.LoopClosureSearchRadius = 8;

            [~, currentPose ,ranges,angles,~] = app.laserScanner.getScannerData();
            scans = lidarScan(ranges,angles);
            addScan(app.slamObj,scans);
            [scansSLAM,poses] = scansAndPoses(app.slamObj);
            app.worldMap = buildMap(scansSLAM,poses,mapResolution,maxRange);
            app.currentPoseOfTheRobot = currentPose;
        end


        function [x, y] = findClosestUnexplored(app)
            map = app.worldMap;
            
            % Get the occupancy grid matrix
            occMatrix = getOccupancy(map);  % Get occupancy matrix
            
            % Define the unexplored threshold (typically 0.5 represents unknown)
            unexploredValue = 0.5;
        
            % Get the robot's position in grid coordinates
            robotX = app.currentPoseOfTheRobot(1);
            robotY = app.currentPoseOfTheRobot(2);
        
            % Initialize the minimum distance and coordinates for closest unexplored area
            minDist = inf;
            x = NaN;
            y = NaN;
            
            % Get the map size
            [mapHeight, mapWidth] = size(occMatrix);
        
            % Loop through the grid cells of the occupancy matrix
            for i = 1:mapHeight
                for j = 1:mapWidth
                    % Check if the cell is unexplored (close to 0.5)
                    occVal = occMatrix(i,j);
                    if abs(occVal - unexploredValue) < 0.01
                        % Compute the Euclidean distance from the robot to the cell (i, j)
                        dist = sqrt((i - robotX)^2 + (j - robotY)^2);
                
                        % Update the minimum distance and corresponding coordinates
                        if dist < minDist
                            minDist = dist;
                            
                            % Use grid2world to transform the coordinates
                            gridCoords = grid2world(app.worldMap, [i, j]);
                            x = i;  % Extract the X coordinate
                            y = j;  % Extract the Y coordinate
                            
                            % Display a debug message with the coordinates and occupancy value
                            fprintf('Debug: Coordinates (i,j) = (%d, %d), transformed to (x,y) = (%d, %d), Occupancy Value = %.2f\n', i, j, gridCoords(1), gridCoords(2), occVal);
                        end
                    end
                end
            end
        end


        function updateMap(app)
            % Plot the occupancy map on MapOccupancy UIAxes
            axes(app.mapOccupancyAxes); % Set MapOccupancy as the current axes
            cla(app.mapOccupancyAxes);  % Clear previous content
            show(app.worldMap, 'Parent', app.mapOccupancyAxes); % Plot occupancy map
            hold(app.mapOccupancyAxes, 'on');
        
            % Plot the robot's current position
            if ~isempty(app.currentPoseOfTheRobot)
                plot(app.mapOccupancyAxes, app.currentPoseOfTheRobot(1), app.currentPoseOfTheRobot(2), 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b', 'DisplayName', 'Robot');
            end
        
            % Plot the planned path if available
            if ~isempty(app.plannedPath)
                plot(app.mapOccupancyAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2);
            end
        
            % Define zoom parameters (e.g., centered around the robot position)
            if ~isempty(app.currentPoseOfTheRobot)
                zoomCenterX = app.intialPosition(1); % Center X at robot's X position
                zoomCenterY = app.intialPosition(2); % Center Y at robot's Y position
                zoomWidth = 11;   % Width of the zoomed-in area 
                zoomHeight = 11;  % Height of the zoomed-in area 
        
                % Set the x and y limits to zoom in around the robot
                xlim(app.mapOccupancyAxes, [zoomCenterX - zoomWidth/2, zoomCenterX + zoomWidth/2]);
                ylim(app.mapOccupancyAxes, [zoomCenterY - zoomHeight/2, zoomCenterY + zoomHeight/2]);
            end
        
            hold(app.mapOccupancyAxes, 'off');
            title(app.mapOccupancyAxes, 'Occupancy Grid Map Built Using Lidar SLAM');
            drawnow;
            app.UpdateTrajectoryMapToo();
        end

        function UpdateTrajectoryMapToo(app)

            axes(app.mapTrajectoryAxes);
            
            % % Clear previous plots
            % cla(obj.mapTrajectoryAxes);
            
            % Display the SLAM trajectory map
            show(app.slamObj, 'Parent', app.mapTrajectoryAxes);

             % Hold on to overlay additional plots
            hold(app.mapTrajectoryAxes, 'on');

             % Plot the path from plannerAStarGrid
            if ~isempty(app.plannedPath)
                plot(app.mapTrajectoryAxes, app.plannedPath(:, 1), app.plannedPath(:, 2), 'r-', 'LineWidth', 2);
            end
            
            % Add a title to the plot
            title(app.mapTrajectoryAxes, 'SLAM Map and Trajectory');

            % Release the hold on the current axes
            hold(app.mapTrajectoryAxes, 'off');

            drawnow;
        end

        function moveTheRobot(app)

             if ~app.targetReached && ~isempty(app.plannedPath)
                % Get the robot's current pose
                robotPosition = app.currentPoseOfTheRobot;  % Assuming you have a method to get the current robot pose
        
                % Compute control inputs using the pure pursuit controller
                [v, omega] = app.purePursuitController(robotPosition);
        
                % Move the robot using the control inputs
                app.baseControl.moveRobot(v, omega,app.minFrontDist);
        
                % Recompute the distance to the goal
                distanceToGoal = norm(robotPosition(1:2) - app.purePursuitController.Waypoints(end, 1:2));
        
                % Check if the robot has reached the goal
                if distanceToGoal <= app.goalRadius
                    app.targetReached = true;
        
                    % Stop the robot
                    app.baseControl.moveRobot(0, 0,app.minFrontDist);
        
                    % Stop the timer
                    stop(app.timerToMoveTheRobot);
                    delete(app.timerToMoveTheRobot);
                end
             end
        end


        function calculateTrajectory(app)
            [x,y] = app.findClosestUnexplored();
            goalPosition = [x,y];
            disp('ai got one');
            disp(goalPosition);

            if ~app.targetReached && ~isempty(goalPosition)
            
               
                inflateRadious = 0.3;
                inflatedMap = copy(app.worldMap);
                                
                inflate(inflatedMap , inflateRadious);
                
                robotX = app.currentPoseOfTheRobot(1);
                robotY = app.currentPoseOfTheRobot(2);

                startPosition = [robotX, robotY];

                pathPlanner = plannerAStarGrid(inflatedMap);
                goalPosition = [2.175, -1.975];

                isStartValid = checkOccupancy(inflatedMap,startPosition) < 0.5;
                isGoalValid = checkOccupancy(inflatedMap,goalPosition) < 0.5;


                goalOccupied = getOccupancy(inflatedMap,goalPosition);
                disp('goalOccupied');
                disp(goalPosition);
                disp(goalOccupied);
                disp('startPosition');
                disp(startPosition);

                if isStartValid && isGoalValid
                    app.plannedPath = plan(pathPlanner, startPosition , goalPosition,'world');
                    app.purePursuitController.Waypoints = app.plannedPath;
                    % disp('firstPath');
                    % disp(firstPath);
                    % 
                    % optimisedPath = optimizePath(firstPath,inflatedMap);
                    % 
                    % app.plannedPath = [optimisedPath(1),optimisedPath(2)];
                    % 
                    % disp('optimised');
                    % disp(app.plannedPath);
                    if strcmp(app.timerToMoveTheRobot.Running, 'off')
                        start(app.timerToMoveTheRobot);
                    end
                else
                    disp(isStartValid);
                    disp(isGoalValid);
                end
            end
        end

        function doShit(app)
            maxrange = 19.2; % meters

            [~, pose ,ranges,angles,app.minFrontDist] = app.laserScanner.getScannerData();
            scan = lidarScan(ranges,angles);
            addScan(app.slamObj,scan);
            
            minRange = 0.1;
            maxRange = 15;
            scan2 = removeInvalidData(scan,'RangeLimits',[minRange maxRange]);
            
            insertRay(app.worldMap,pose,scan2,maxrange);
            app.currentPoseOfTheRobot = pose;
            if isempty(app.intialPosition)
                app.intialPosition = pose;
            end
        end

        % Update the camera feed (in a loop or timer)
        function updateCameraFeed(app)
            % Get the current camera frame from CoppeliaSim
            [res, ~, image] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_buffer);
            
            if res == app.connection.sim.simx_return_ok
                set(app.hImage, 'CData', image);  % Update the image in UIAxes
                drawnow;  % Refresh display
            end
        end

        function componentsInitialSetup(app)
            
            % Retrieve the handle for the robot object from CoppeliaSim
            [res, robotHandle] = app.connection.sim.simxGetObjectHandle(app.connection.clientID, '/Robot', app.connection.sim.simx_opmode_blocking);

            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [rightMotor, ~] = app.connection.getHandle('/Robot/rightMotor');
            [leftMotor, ~] = app.connection.getHandle('/Robot/leftMotor');
            
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [rightMotor, leftMotor];
            
            wheelRadius = 0.02;
            % Initialize the BaseControl object
            app.baseControl = BaseControl(app.connection,robotHandle,wheelHandles,wheelRadius);
            
        
            % Initialize SLAM algorithm with new parameters
            maxRange = 10; % Slightly below the max Lidar range
            mapResolution = 20; % 20 cells per meter, 5cm precision
            loopClosureThreshold = 210;
            loopClosureSearchRadius = 8;
                   
            
            if res == app.connection.sim.simx_return_ok
                % Pass sim, clientID, and robotHandle to RobotPose constructor
                app.robotPose = RobotPose(app.connection.sim, app.connection.clientID, robotHandle);
                disp('Robot handle retrieved and RobotPose object initialized.');
            else
                disp('Failed to retrieve robot handle.');
                return;  % Exit if robot handle is not retrieved
            end
            
            % Initialize LaserScanner and set the initial robot position
            app.laserScanner = LaserScanner(app.connection, app.robotPose);
        
            % Initialize the SLAM handler object
            app.slamHandler = SLAMHandler(app.laserScanner, app.robotPose, mapResolution, maxRange, loopClosureThreshold, loopClosureSearchRadius, app.mapTrajectoryAxes, app.mapOccupancyAxes);
        
            % Initialize Explorer object with references to the necessary components
            % Pass sim, clientID, and robotHandle to the Explorer constructor
            app.explorer = Explorer(app.connection, app.baseControl, app.slamHandler, app.laserScanner, app.robotPose, app.connection.sim, app.connection.clientID, robotHandle);  
            
            app.shitInitiater();

            % Get the vision sensor handle
            [app.camHandle, ~] = app.connection.getHandle('/Camera');
            
            % Start image streaming
            [~, resolution, ~] = app.connection.sim.simxGetVisionSensorImage2(app.connection.clientID, app.camHandle, 0, app.connection.sim.simx_opmode_streaming);
            
            % Initialize image display on UIAxes
            app.hImage = imshow(zeros(resolution(2), resolution(1), 3), 'Parent', app.cameraAxes);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.buttonsInitialSetup();
            app.timerToDoShit = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 0.3, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.doShit());
            
            app.timerToMoveTheRobot = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 2.5, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.moveTheRobot());

            app.timerToUpdateMap = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 1, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.updateMap());
            app.timerRecalculatePath = timer('ExecutionMode', 'fixedRate', ...
                                   'Period', 5, ... % Update every x second; 
                                   'TimerFcn', @(~,~) app.calculateTrajectory());

            app.cameraTimer = timer('ExecutionMode', 'fixedRate', ...
                        'Period', 0.1, ...  % Adjust for desired frame rate
                        'TimerFcn', @(~,~) app.updateCameraFeed());
            

        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                success = app.connection.connect();
                if success
                    disp('Connected to CoppeliaSim.');
                    app.componentsInitialSetup();
                    
                    % Change the connect button to disconnect
                    app.toggleConnectButton();

                    % Enable Simulation controls
                    app.toggleSimulationButton('on');
                else
                    %failed to connect to Coppelia
                     uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end
            else
                app.killAllTimers();
                %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                    app.toggleAutoExploreButton('off');
                end

                app.slamHandler.stopUpdating();
                app.explorer.stopExploration();
                app.connection.disconnect();

                % Change back the connect button to connect
                app.toggleConnectButton();

                % Disable UI controls 
                app.toggleControls("off");
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1

                 if ~app.connection.isSimulationRunning
                    app.connection.startSimulation();
                 
                    if app.connection.isSimulationRunning
                         % Enable UI controls
                        app.toggleControls('on');
                        app.toggleAutoExploreButton('on');
                        start(app.cameraTimer); 
                    end

                 else
                    app.killAllTimers();
                    app.slamHandler.stopUpdating();
                    app.explorer.stopExploration();
                    
                     % Stop the simulation
                    app.connection.stopSimulation();
                    
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.toggleControls('off');
                        app.toggleAutoExploreButton('off');
                        disp('Simulation stopped.');
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SpeedKnob
        function KnobSpeedValueChanged(app, event)
            app.wheelsSpeed  = app.SpeedKnob.Value;
        end

        % Value changed function: BtnBackward, BtnCCW, BtnCW, BtnForward, 
        % ...and 3 other components
        function BtnMovementValueChanged(app, event)
            value = event.Source.Value;
            
            movementType = event.Source.Tag;  % Get the movement type from the button's tag
            
            % Create a list of all movement buttons
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, app.BtnCW, app.BtnCCW};

            % Take out from the list the pressed button
            pressedButton = event.Source;
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Loop through all the other movement buttons and set their value to 0
                for i = 1:length(remainingButtons)
                    remainingButtons{i}.Value = 0;
                end

            % if the Full stop button is clicked
            if strcmp(movementType, 'STOP')
                % Stop the robot
                if app.slamHandler.isUpdating
                    app.slamHandler.stopUpdating();
                end
                if app.explorer.isExploring
                    app.explorer.stopExploration();
                end
                app.baseControl.stop();
                app.BtnFullStop.Value = 0;
            else
                if strcmp(app.timerToDoShit.Running, 'off')
                    start(app.timerToDoShit);
                end
                if strcmp(app.timerToUpdateMap.Running, 'off')
                    start(app.timerToUpdateMap);
                end
                if ~app.slamHandler.isUpdating
                    app.slamHandler.startUpdating();
                end
                % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.baseControl.move(app.baseControl.WheelMovements.(movementType));
                else
                    app.baseControl.stop();
                end
            end
            app.toggleBuildMapButtons();
        end

        % Value changed function: AutoexploreSwitch
        function AutoexploreSwitchValueChanged(app, event)
            if ~app.explorer.isExploring
                if strcmp(app.timerToUpdateMap.Running, 'off')
                    start(app.timerToUpdateMap);
                end
                if strcmp(app.timerToDoShit.Running, 'off')
                    start(app.timerToDoShit);
                end
                if strcmp(app.timerRecalculatePath.Running, 'off')
                    start(app.timerRecalculatePath);
                end
                % Start exploration
                app.SpeedKnob.Value = app.autoExploreSpeed;
                if ~app.slamHandler.isUpdating
                    app.slamHandler.startUpdating();
                end    
                app.explorer.startExploration();
                disp('Exploration started.');
            else
                % Stop exploration
                app.SpeedKnob.Value = app.wheelsSpeed;
                app.explorer.stopExploration();
                disp('Exploration stopped.');
                app.killAllTimers();
            end
            app.toggleBuildMapButtons();
        end

        % Close request function: UIFigure
        function AppCloseRequested(app, event)
               %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                end
                if app.slamHandler.isUpdating
                    app.slamHandler.stopUpdating();
                end
                if app.explorer.isExploring
                    app.explorer.stopExploration();
                end
                app.connection.disconnect();
                delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @AppCloseRequested, true);
            app.mapTrajectoryAxes = uiaxes(app.UIFigure);
            title(app.mapTrajectoryAxes, 'Trajectory map')
            xlabel(app.mapTrajectoryAxes, 'X')
            ylabel(app.mapTrajectoryAxes, 'Y')
            zlabel(app.mapTrajectoryAxes, 'Z')
            app.mapOccupancyAxes = uiaxes(app.UIFigure);
            title(app.mapOccupancyAxes, 'Occupancy map')
            xlabel(app.mapOccupancyAxes, 'X')
            ylabel(app.mapOccupancyAxes, 'Y')
            zlabel(app.mapOccupancyAxes, 'Z')
            app.cameraAxes = uiaxes(app.UIFigure);
            title(app.cameraAxes, 'Camera')
            app.cameraAxes.XTick = [];
            app.cameraAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SimulationLampLabel = uilabel(app.UIFigure);
            app.SimulationLampLabel.Text = 'Simulation';
            app.SimulationLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoexploreSwitchValueChanged, true);
            app.AutoexploreSwitch.Enable = 'off';
            app.AutoexploreLabel = uilabel(app.Panel_3);
            app.AutoexploreLabel.Enable = 'off';
            app.AutoexploreLabel.Text = 'Auto explore';
            app.MapbuildingLabel = uilabel(app.UIFigure);
            app.MapbuildingLabel.Text = 'Map building';
            app.MapBuildingLamp = uilamp(app.UIFigure);
            app.Panel_2 = uipanel(app.UIFigure);
            app.SpeedLabel = uilabel(app.Panel_2);
            app.SpeedLabel.Enable = 'off';
            app.SpeedLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.Panel_2, 'continuous');
            app.SpeedKnob.Limits = [0 50];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @KnobSpeedValueChanged, true);
            app.SpeedKnob.Enable = 'off';
            app.BuildMapBackground = uipanel(app.UIFigure);
            app.BuildMapImage = uiimage(app.UIFigure);
            app.BuildMapImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'map.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end